classdef CorData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CorDataLabel                    matlab.ui.control.Label
        JohnsHopkinsaatafromdataworldLabel  matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        RefreshButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ConfirmedCurveTab               matlab.ui.container.Tab
        CountryAxes                     matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        ConfirmedTable                  matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        StandarddeviationindaysLabel    matlab.ui.control.Label
        StandarddeviationindaysSpinner  matlab.ui.control.Spinner
        ShownormaldistributionfitCheckBox  matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        ShowconfirmedCheckBox           matlab.ui.control.CheckBox
        ShownewconfirmedCheckBox        matlab.ui.control.CheckBox
        Panel_3                         matlab.ui.container.Panel
        CountryDropDownLabel            matlab.ui.control.Label
        CountryDropDown                 matlab.ui.control.DropDown
        StateProvinceDropDownLabel      matlab.ui.control.Label
        StateProvinceDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        dwApiKey='';                    % apikey for data.world
        defaultState='';                % default state/province to show
        defaultCountry='';              % default country to show
        alwaysDownload=true;            % force to download even if local data file exists
        localDataFile='corData.csv'     % Name of local data file
        wholeDataTable                  % whole data table from data.world
        hasData = false;                % has data.world in memory
        countryTotal                    % total confiremed for each country
        countryArray                    % array of all countries and regions
        cachedFile='cachedCountry.mat'  % cached countries
    end
    
    methods (Access = private)
        
        function readConfig(app)
            % Use a config file to get the apikey of data.world. Also read
            % in some optional configure info
            try
                configName = 'app_config.m';
                if isfile(configName)
                    fid = fopen(configName, 'r');
                    % read the whole file
                    cmdStringMatrix = fscanf(fid, '%c');
                    % eval the whole file
                    eval(cmdStringMatrix);
                    if exist('app_init_dw_apikey', 'var')
                        app.dwApiKey = app_init_dw_apikey;
                    end
                    if exist('app_init_show_state', 'var')
                        app.defaultState = app_init_show_state;
                    end
                    if exist('app_init_show_country', 'var')
                        app.defaultCountry = app_init_show_country;
                    end
                    if exist('app_init_always_download', 'var')
                        app.alwaysDownload = app_init_always_download;
                    end
                    app.updateStatus('Config file loaded. Click Refresh to start.');
                else
                    app.updateStatus('Config file not found');
                end
                app.updateStatus('Config file loaded. Click Fresh to start.');
            catch e
                app.updateStatus('Failed to read config file');
            end
        end
        
        function showError(app, err)
            app.StatusLabel.FontColor = 'Red';
            app.StatusLabel.Text = err;
        end
        function showWarning(app, warning)
            app.StatusLabel.FontColor = 'Blue';
            app.StatusLabel.Text = warning;
        end
        function updateStatus(app, msg)
            % Don't erase warning
            if ~strcmp(app.StatusLabel.FontColor, 'Blue')
                app.StatusLabel.Text = msg;     
            end
        end
        
        function download(app)
            app.updateStatus('Downloading from data.world');
            data_world = ['https://query.data.world/s/', app.dwApiKey];
            websave(app.localDataFile, data_world);
            app.updateStatus('Data saved as local file');
        end
        
        function drawActiveTab(app)
            selectedTab = app.TabGroup.SelectedTab;
            % read selected country
            if app.hasData
                country = app.CountryDropDown.Value;
                % read the confirmed
                if app.StateProvinceDropDown.Value == "all"
                    confirmed = app.wholeDataTable(ismember(app.wholeDataTable.Case_Type, {'Confirmed'}) & ismember(app.wholeDataTable.Country_Region, {country}), :);
                    title = ['Confirmed cases for ' country];
                else
                    state = app.StateProvinceDropDown.Value;
                    confirmed = app.wholeDataTable(ismember(app.wholeDataTable.Case_Type, {'Confirmed'}) & ismember(app.wholeDataTable.Province_State, {state}), :);
                    title = ['Confirmed cases for ' state];
                end
                app.CountryAxes.Title.String = title;
                % Sum data with the same Date
                sorted = sortrows(confirmed(:, {'Cases', 'Difference', 'Date'}), 2);
                sorted.Date = categorical(sorted.Date);
                summed = varfun(@sum, sorted, 'GroupingVariables', 'Date');
                
                hold_on = false;
                if app.ShowconfirmedCheckBox.Value
                    % Show confirmed case
                    plot(app.CountryAxes, summed.Date, summed.sum_Cases);
                    hold(app.CountryAxes, 'on');
                    hold_on = true;
                end
                
                if app.ShownewconfirmedCheckBox.Value
                    % show new confirmed case
                    bar(app.CountryAxes, summed.Date, summed.sum_Difference);
                end
                
                if hold_on
                    hold(app.CountryAxes, 'off');
                end
            else
                app.showError('Failed to readData!');
            end
        end
        
        function status = tryStateSelection(app)
            % Check if the default state in app_config.m is valid
            status = false;
            if ~isempty(app.defaultState)
                filtered = app.wholeDataTable(ismember(app.wholeDataTable.Province_State, {app.defaultState}), :);
                sz = size(filtered);
                if sz(1) > 0
                    country = char(filtered(1,:).Country_Region);
                    app.CountryDropDown.Value = country;
                    
                    % Find all states/provinces for this country
                    app.findAllStates(country);
                    app.StateProvinceDropDown.Value = app.defaultState;
                    status = true;
                end
            end
        end
        function findAllStates(app, country)
            all_states_data = app.wholeDataTable(ismember(app.wholeDataTable.Country_Region, {country}), :);
            all_states = unique(string(all_states_data.Province_State));
            app.StateProvinceDropDown.Items = ["all"; all_states];
        end
        function findAllCountries(app)
            if app.hasData
                char_latest = char(max(app.wholeDataTable.Date));
                lastest_country = app.wholeDataTable(ismember(app.wholeDataTable.Date, {char_latest}), :);
                cases_country = lastest_country(:, {'Cases', 'Country_Region'});
                cases_country.Country_Region = categorical(cases_country.Country_Region);
                app.countryTotal = varfun(@sum, cases_country, 'GroupingVariables', 'Country_Region');
                app.countryArray = string(app.countryTotal.Country_Region);
                app_country_array = app.countryArray;
                save(app.cachedFile, 'app_country_array');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateStatus('');
            app.readConfig();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            if isempty(app.dwApiKey)
                app.showError('Put data.world apikey in app_config.m please.')
            else
                if ~isfile(app.localDataFile) || app.alwaysDownload
                    % downlaod it first
                    app.download();
                end
                try
                    app.updateStatus('Reading local data file....');
                    if ~app.hasData
                        % expensive
                        app.wholeDataTable = readtable(app.localDataFile);
                    end
                    app.hasData = true;
                    app.updateStatus('Figuring out all the countries...');
                    if ~isfile(app.cachedFile)
                        app.findAllCountries()
                    else
                        load(app.cachedFile);
                        app.countryArray = app_country_array;
                    end
                    app.CountryDropDown.Items = app.countryArray;
                    app.updateStatus("Try to set default from config");
                    status = app.tryStateSelection();
                    if ~status || isempty(app.defaultState)
                        % no valude default value for state from config. Try country
                        app.showWarning('default state in app_config.m is invalid.');
                        try
                            app.CountryDropDown.Value = app.defaultCountry;
                            % Good country
                            app.findAllStates(app.defaultCountry);
                        catch ME
                            app.showWarning('default country in app_config.m is invalid.');
                            app.defaultCountry = app.CountryDropDown.Items(1);
                            app.findAllStates(app.defaultCountry);
                        end
                        app.StateProvinceDropDown.Value = "all";
                    end
                    app.updateStatus('Done reading data.')
                    app.drawActiveTab();
                catch ME
                    app.showError('Failed to read local data file.');
                    app.hasData = false;
                end
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.drawActiveTab();
        end

        % Value changed function: CountryDropDown
        function CountryDropDownValueChanged(app, event)
            selected_country = app.CountryDropDown.Value;
            app.findAllStates(selected_country);
            % reset selected state to all
            app.StateProvinceDropDown.Value = "all";
            app.drawActiveTab();
        end

        % Value changed function: StateProvinceDropDown
        function StateProvinceDropDownValueChanged(app, event)
            app.drawActiveTab();            
        end

        % Value changed function: ShowconfirmedCheckBox
        function ShowconfirmedCheckBoxValueChanged(app, event)
            app.drawActiveTab();            
        end

        % Value changed function: ShownewconfirmedCheckBox
        function ShownewconfirmedCheckBoxValueChanged(app, event)
            app.drawActiveTab();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CorDataLabel = uilabel(app.UIFigure);
            app.CorDataLabel.Text = 'CorData';
            app.JohnsHopkinsaatafromdataworldLabel = uilabel(app.UIFigure);
            app.JohnsHopkinsaatafromdataworldLabel.Text = 'Johns Hopkins aata from data.world';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'StatusLabel';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ConfirmedCurveTab = uitab(app.TabGroup);
            app.ConfirmedCurveTab.Title = 'Confirmed Curve';
            app.CountryAxes = uiaxes(app.ConfirmedCurveTab);
            title(app.CountryAxes, 'Title')
            xlabel(app.CountryAxes, 'X')
            ylabel(app.CountryAxes, 'Y')
            app.ConfirmedTable = uitab(app.TabGroup);
            app.ConfirmedTable.Title = 'Confirmed Table';
            app.UITable = uitable(app.ConfirmedTable);
            app.UITable.ColumnName = {'Date'; 'Confirmed Cases'; 'Difference'};
            app.UITable.RowName = {};
            app.Panel = uipanel(app.UIFigure);
            app.StandarddeviationindaysLabel = uilabel(app.Panel);
            app.StandarddeviationindaysLabel.Text = 'Standard deviation in days:';
            app.StandarddeviationindaysSpinner = uispinner(app.Panel);
            app.StandarddeviationindaysSpinner.Value = 10;
            app.ShownormaldistributionfitCheckBox = uicheckbox(app.Panel);
            app.ShownormaldistributionfitCheckBox.Text = 'Show normal distribution fit';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ShowconfirmedCheckBox = uicheckbox(app.Panel_2);
            app.ShowconfirmedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowconfirmedCheckBoxValueChanged, true);
            app.ShowconfirmedCheckBox.Text = 'Show confirmed';
            app.ShownewconfirmedCheckBox = uicheckbox(app.Panel_2);
            app.ShownewconfirmedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShownewconfirmedCheckBoxValueChanged, true);
            app.ShownewconfirmedCheckBox.Text = 'Show new confirmed';
            app.ShownewconfirmedCheckBox.Value = true;
            app.Panel_3 = uipanel(app.UIFigure);
            app.CountryDropDownLabel = uilabel(app.Panel_3);
            app.CountryDropDownLabel.Text = 'Country';
            app.CountryDropDown = uidropdown(app.Panel_3);
            app.CountryDropDown.Items = {'US', 'China', 'Japan', 'United Kingdom', 'France', 'Spain', 'Germany'};
            app.CountryDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryDropDownValueChanged, true);
            app.CountryDropDown.Value = 'US';
            app.StateProvinceDropDownLabel = uilabel(app.Panel_3);
            app.StateProvinceDropDownLabel.Text = 'State/Province';
            app.StateProvinceDropDown = uidropdown(app.Panel_3);
            app.StateProvinceDropDown.ValueChangedFcn = createCallbackFcn(app, @StateProvinceDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end